classdef FiberModesCalcGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiberModesCalcUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        CalcButton              matlab.ui.control.Button
        lp_p                    matlab.ui.control.NumericEditField
        pEditFieldLabel         matlab.ui.control.Label
        LPLabel                 matlab.ui.control.Label
        lp_m                    matlab.ui.control.NumericEditField
        mEditFieldLabel         matlab.ui.control.Label
        lambda                  matlab.ui.control.NumericEditField
        LambdaLabel             matlab.ui.control.Label
        n_core                  matlab.ui.control.NumericEditField
        N_coreLabel             matlab.ui.control.Label
        n_clad                  matlab.ui.control.NumericEditField
        N_cladLabel             matlab.ui.control.Label
        core_d                  matlab.ui.control.NumericEditField
        d_coreLabel             matlab.ui.control.Label
        clad_d                  matlab.ui.control.NumericEditField
        d_cladLabel             matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        modes                   matlab.ui.control.UIAxes
        beta                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    end

    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CalcButtonPushed(app)
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            try
                [E_comp, H_comp, r_s, fi_s] = ModeSolver(app.beta,app.lambda.Value, app.core_d.Value/2, app.clad_d.Value/2, 1, [app.lp_m.Value,app.lp_p.Value], 0, [app.n_core.Value app.n_clad.Value], "LP");
                f = figure();
                PlotMode(app.modes,E_comp, H_comp, r_s, fi_s, app.core_d.Value/2, app.clad_d.Value/2, "whole");
                cla(app.modes);
                copyobj(f.CurrentAxes.Children, app.modes);
                close(f);
            catch ME
                message = sprintf('Error in application:\n%s', ME.message);
                uiwait(warndlg(message));
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FiberModesCalcUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {685, 685};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {156, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiberModesCalcUIFigure = uifigure('Visible', 'off');
            app.FiberModesCalcUIFigure.Name = 'FiberModesCalc';
            app.FiberModesCalcUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.FiberModesCalcUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.d_cladLabel = uilabel(app.LeftPanel);
            app.d_cladLabel.Interpreter = 'latex';
            app.d_cladLabel.Text = '$d_{clad}$ $[\mu m]$';
            app.clad_d = uieditfield(app.LeftPanel, 'numeric');
            app.clad_d.Limits = [0 Inf];
            app.clad_d.Value = 125;
            app.d_coreLabel = uilabel(app.LeftPanel);
            app.d_coreLabel.Interpreter = 'latex';
            app.d_coreLabel.Text = '$d_{core}$ $[\mu m]$';
            app.core_d = uieditfield(app.LeftPanel, 'numeric');
            app.core_d.Limits = [0 Inf];
            app.core_d.Value = 8.3;
            app.N_cladLabel = uilabel(app.LeftPanel);
            app.N_cladLabel.Interpreter = 'latex';
            app.N_cladLabel.Text = '$N_{clad}$';
            app.n_clad = uieditfield(app.LeftPanel, 'numeric');
            app.n_clad.Limits = [0 Inf];
            app.n_clad.ValueDisplayFormat = '%11.6g';
            app.n_clad.Value = 1.444;
            app.N_coreLabel = uilabel(app.LeftPanel);
            app.N_coreLabel.Interpreter = 'latex';
            app.N_coreLabel.Text = '$N_{core}$';
            app.n_core = uieditfield(app.LeftPanel, 'numeric');
            app.n_core.Limits = [0 Inf];
            app.n_core.ValueDisplayFormat = '%11.6g';
            app.n_core.Value = 1.4513;
            app.LambdaLabel = uilabel(app.LeftPanel);
            app.LambdaLabel.Interpreter = 'latex';
            app.LambdaLabel.Text = '$\Lambda$ $[\mu m]$';
            app.lambda = uieditfield(app.LeftPanel, 'numeric');
            app.lambda.Limits = [0 Inf];
            app.lambda.Value = 1.55;
            app.mEditFieldLabel = uilabel(app.LeftPanel);
            app.mEditFieldLabel.Interpreter = 'latex';
            app.mEditFieldLabel.Text = 'm';
            app.lp_m = uieditfield(app.LeftPanel, 'numeric');
            app.lp_m.Limits = [0 Inf];
            app.lp_m.RoundFractionalValues = 'on';
            app.LPLabel = uilabel(app.LeftPanel);
            app.LPLabel.Interpreter = 'latex';
            app.LPLabel.Text = 'LP';
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Interpreter = 'latex';
            app.pEditFieldLabel.Text = 'p';
            app.lp_p = uieditfield(app.LeftPanel, 'numeric');
            app.lp_p.Limits = [0 Inf];
            app.lp_p.Value = 1;
            app.CalcButton = uibutton(app.LeftPanel, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calc';
            app.RightPanel = uipanel(app.GridLayout);
            app.beta = uiaxes(app.RightPanel);
            title(app.beta, 'Dispersion Equation Solution')
            xlabel(app.beta, 'X')
            ylabel(app.beta, 'Y')
            zlabel(app.beta, 'Z')
            app.beta.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.modes = uiaxes(app.RightPanel);
            title(app.modes, 'Modal Field Distribution')
            xlabel(app.modes, 'X')
            zlabel(app.modes, 'Z')
            % Show the figure after all components are created
            app.FiberModesCalcUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiberModesCalcGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiberModesCalcUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiberModesCalcUIFigure)
        end
    end
end